classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        DebuggingetcPanel        matlab.ui.container.Panel
        outputBar                matlab.ui.control.EditField
        TabGroup                 matlab.ui.container.TabGroup
        NiryoTab                 matlab.ui.container.Tab
        GetPositionButton_2      matlab.ui.control.Button
        MoveNiryoButton          matlab.ui.control.Button
        EndEffectorMoverLabel    matlab.ui.control.Label
        ZPosEditField            matlab.ui.control.NumericEditField
        ZPosEditFieldLabel       matlab.ui.control.Label
        YPosEditField            matlab.ui.control.NumericEditField
        YPosEditFieldLabel       matlab.ui.control.Label
        XPosEditField            matlab.ui.control.NumericEditField
        XPosEditFieldLabel       matlab.ui.control.Label
        JOINT6Knob_2             matlab.ui.control.Knob
        JOINT6Knob_2Label        matlab.ui.control.Label
        JOINT5Knob_2             matlab.ui.control.Knob
        JOINT5Knob_2Label        matlab.ui.control.Label
        JOINT4Knob_2             matlab.ui.control.Knob
        JOINT4Knob_2Label        matlab.ui.control.Label
        JOINT3Knob_2             matlab.ui.control.Knob
        JOINT3Knob_2Label        matlab.ui.control.Label
        JOINT2Knob_2             matlab.ui.control.Knob
        JOINT2Knob_2Label        matlab.ui.control.Label
        JOINT1Knob_2             matlab.ui.control.Knob
        JOINT1Knob_2Label        matlab.ui.control.Label
        UR3Tab                   matlab.ui.container.Tab
        GetPositionButton        matlab.ui.control.Button
        ZPosEditField_2          matlab.ui.control.NumericEditField
        ZPosEditField_2Label     matlab.ui.control.Label
        YPosEditField_2          matlab.ui.control.NumericEditField
        YPosEditField_2Label     matlab.ui.control.Label
        XPosEditField_2          matlab.ui.control.NumericEditField
        XPosEditField_2Label     matlab.ui.control.Label
        MoveUR3Button            matlab.ui.control.Button
        EndEffectorMoverLabel_2  matlab.ui.control.Label
        JOINT6Knob               matlab.ui.control.Knob
        JOINT6KnobLabel          matlab.ui.control.Label
        JOINT5Knob               matlab.ui.control.Knob
        JOINT5KnobLabel          matlab.ui.control.Label
        JOINT4Knob               matlab.ui.control.Knob
        JOINT4KnobLabel          matlab.ui.control.Label
        JOINT3Knob               matlab.ui.control.Knob
        JOINT3KnobLabel          matlab.ui.control.Label
        JOINT2Knob               matlab.ui.control.Knob
        JOINT2KnobLabel          matlab.ui.control.Label
        JOINT1Knob               matlab.ui.control.Knob
        JOINT1KnobLabel          matlab.ui.control.Label
        RightPanel               matlab.ui.container.Panel
        SensorPigTripButton      matlab.ui.control.Button
        PigMoverSlider           matlab.ui.control.Slider
        PigMoverSliderLabel      matlab.ui.control.Label
        RunSequenceButton        matlab.ui.control.Button
        Lamp_2                   matlab.ui.control.Lamp
        Switch_2                 matlab.ui.control.ToggleSwitch
        ESTOPButton              matlab.ui.control.Button
        Lamp                     matlab.ui.control.Lamp
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        myNiryoOne;
        myNiryoOneLink1 = 0;
        myNiryoOneLink2 = 0;
        myNiryoOneLink3 = 0;
        myNiryoOneLink4 = 0;
        myNiryoOneLink5 = 0;
        myNiryoOneLink6 = 0;

        myUR3;
        myUR3Link1 = 0;
        myUR3Link2 = -pi/2;
        myUR3Link3 = 0;
        myUR3Link4 = 0;
        myUR3Link5 = -pi/2;
        myUR3Link6 = 0;
        

        initialPlatePose = transl(0.15, 0.061, 0.1) * trotx(pi/2) ; 
        initialSpongePose = transl(0.747,-0.049,0.072); 
        plate;
        sponge;

        piggy;
        env;

        eStopFlag = -1; % not pressed
        resumeFlag = -1; % not pressed
        barrierFlag = -1; % not triggered
        prevFlag = 0;

        esp32;        
        debouncerTick = 0;
        debouncerAmount = 10;

        niryoWaypoints = {transl([0.22,0.061,0.195]) ... % plate 1
            , transl([0.358,0.196,0.244]) ... % point2 2
            , transl([0.358,0.196,-0.005]) ... % dunk 3
            , transl([0.358,0.196,0.406]) ... % point4 4  RMRC in lab happens for same axis, goes straight, is it kay if the points are short?
            , transl([0.6975,0.263,0.3915]) ... % hold 5
            , transl([0.6975,0.263,0.3915]) ... % hold 6
            , transl([0.6975,0.263,0.3915]) ... % hold 7
            , transl([0.6975,0.263,0.3915]) ... % hold 8
            , transl([0.6975,0.263,0.3915]) ... % hold 9
            , transl([0.747,-0.049,0.072]) ... % sponge 10
            , transl([0.726,0.1495,0.4695]) * trotx(pi/2) ... % scrub position 11
            , transl([0.726,0.1495,0.4695]) * trotx(pi/2) ... % scrub 12
            , transl([0.726,0.1495,0.4695]) * trotx(pi/2) ... % srub 13
            , transl([0.726,0.1495,0.4695]) ... % scrub position 14
            , transl([0.747,-0.049,0.072]) ... % sponge 15
            , transl([0.3203,-0.01,0.423]) * trotx(-pi/2) ... % reset 16
            , transl([0.3203,-0.01,0.423]) * trotx(-pi/2) ... % reset 17
            , transl([0.3203,-0.01,0.423]) * trotx(-pi/2)}; % reset 18
        
        Ur3Waypoints = {transl([0.747,-0.049,0.072]) * troty(pi) ... % sponge 1
            , transl([0.7795, 0.1269, 0.3629]) * troty(pi) ...  % srub position 2
            , transl([0.7795, 0.1269, 0.3629]) * troty(pi) ...  % srub position 3
            , transl([0.7795, 0.1269, 0.3629]) * troty(pi) ...  % srub position 4
            , transl([0.7795, 0.1269, 0.3629]) * troty(pi) * trotx(-pi/2) ...  % srub position 5
            , transl([0.7795, 0.1269, 0.3629]) * troty(pi) * trotx(-pi/2) ...  % srub 6
            , transl([0.7795, 0.1269, 0.3629]) * troty(pi) * trotx(-pi/2) ...  % srub 7
            , transl([0.747,-0.049,0.072]) * troty(pi) ... % sponge 8
            , transl([0.8247, 0.2134, 0.5401]) * troty(pi) ...  % hold 9
            , transl([0.8247, 0.2134, 0.5401]) * troty(pi) ...  % hold 10
            , transl([0.8247, 0.2134, 0.5401]) * troty(pi) ...  % hold 11
            , transl([0.8247, 0.2134, 0.5401]) * troty(pi) ...  % hold 12
            , transl([0.8247, 0.2134, 0.5401]) * troty(pi) ...  % hold 13
            , transl([0.6979, 0.2587, -0.04671]) * troty(pi) ...   % dunk 14
            , transl([0.6979, 0.2587, 0.5401]) * troty(pi) ...   % up 15
            , transl([1.165, 0.3, 0.178]) * troty(pi) ... % place 16
            , transl([0.9346, -0.1223, 0.5269]) * troty(pi) ...  % reset 17
            , transl([0.9346, -0.1223, 0.5269]) * troty(pi)};  % reset 18
    end

    
    methods (Access = private)
        
        function clean(app)
            % stub for the unfinished cleaning function that also included

            % calculate robot trajectories
            steps = 50;
            niryoTrajectoryQmatrix = calculateRobotTrajectory(app.myNiryoOne, app.niryoWaypoints, steps);
            ur3TrajectoryQmatrix = calculateRobotTrajectory(app.myUR3, app.Ur3Waypoints, steps);

            % Using a 3d array because we were going to use trajectories
            % based on joint angles instead of steps. This makes our code
            % more modular. (never ended up doing joints though, as
            % collision didn't end up working in time)
            for waypoint = 1:size(niryoTrajectoryQmatrix, 2)
                jointStep = 1;
                while jointStep <= size(niryoTrajectoryQmatrix{1}, 1)
                    [intersectionPoint,check] = app.piggy.checkPigIntersection(); %#ok<ASGLU>
                    if check == 0 || check == 3
                        app.barrierFlag = -1; % untripped
                    else
                        app.barrierFlag = 1; %tripped
                    end
                    if safety(app)
                        app.outputBar.Value = "I'm cleaning (btw, the plate and sponge are magnetic)";

                        % animate each robot by one step
                        app.myNiryoOne.model.animate(niryoTrajectoryQmatrix{waypoint}(jointStep,:));
                        app.myUR3.model.animate(ur3TrajectoryQmatrix{waypoint}(jointStep,:));

                        % update each robot's endeffector pose
                        % Calculating this before the loop would result in
                        % more efficient animation. We could have stored it
                        % in a 3d array like the trajectories
                        niryoEndEffectorPose = app.myNiryoOne.model.fkine(niryoTrajectoryQmatrix{waypoint}(jointStep,:)).T;
                        ur3EndEffectorPose = app.myUR3.model.fkine(ur3TrajectoryQmatrix{waypoint}(jointStep,:)).T;
            
                        if (waypoint <= 8)
                            platePose = niryoEndEffectorPose * transl(0, 0, -0.05) * trotx(pi/2);
                            app.plate.PlotPlate(platePose);   
                            spongePose = ur3EndEffectorPose * transl(0, 0, 0.025);
                            app.sponge.PlotSponge(spongePose);
            
                        elseif (waypoint == 9)
                            spongePose = niryoEndEffectorPose * transl(0, 0, 0.05);
                            app.sponge.PlotSponge(spongePose);
            
                        elseif (waypoint >= 10 && waypoint <= 14)
                            platePose = ur3EndEffectorPose * transl(0, 0, 0.025) * trotx(pi/2);
                            app.plate.PlotPlate(platePose);   
                            spongePose = niryoEndEffectorPose * transl(0, 0, 0.05);
                            app.sponge.PlotSponge(spongePose);
                        
                        elseif (waypoint == 15)
                            spongePose = niryoEndEffectorPose * transl(0, 0, 0.15);
                            app.sponge.PlotSponge(spongePose);
                            platePose = ur3EndEffectorPose * transl(0, 0, 0.025) * trotx(pi/2);
                            app.plate.PlotPlate(platePose);
                            
                        elseif (waypoint >= 16)
                            app.plate.Hide();
                            app.sponge.Hide();
                            app.plate = RobotPlate(transl(1.165, 0.3, 0));
                            app.sponge = RobotSponge(transl(0.747,-0.049,0));
                        end
                        jointStep = jointStep + 1; % ensure resuming results 
                        % in animating the correct step. Without this, 
                        % it would resume as if the animation was still ongoing
                    else
                        app.outputBar.Value = ':( Emergency stop activated! Cleaning process paused.';
                        pause(0.01);
                    end
                end
            end
        end
        
        function hardwareEstop(app)
            app.eStopFlag = app.eStopFlag * -1; % toggle var
            if app.eStopFlag > 0
                app.resumeFlag = 1;

                debuggingOutput= "E-stop armed";
                app.Switch_2.Value = 'Pause'; % flick resume toggle

                app.Lamp.Color = [1 0 0]; % make gui estop and resume red
                app.Lamp_2.Color = [1 0 0];
            else
                app.eStopFlag = -1;
                app.Lamp.Color = [1 1 0]; % make gui estop green
                debuggingOutput = "E-stop disarmed";
            end
            app.outputBar.Value = debuggingOutput;  
        end
        
        function checkHardwareEstop(app)
            flush(app.esp32);    % clears the serial buffer to read the latest msg from ESP
            hardwareEstopValue = read(app.esp32,1,"char");
            app.debouncerTick = app.debouncerTick + 1; % tick debouncer
            if hardwareEstopValue == '1' && app.debouncerTick > app.debouncerAmount
                app.hardwareEstop();
                app.debouncerTick = 0;
            end
        end
        
        function safetyFlag = safety(app) % returns 1 if safe, 0 if not
            % checkHardwareEstop(app);
            if app.eStopFlag == -1 && app.resumeFlag == -1 && app.barrierFlag == -1
                safetyFlag = 1;
            else
                safetyFlag = 0;
            end

            if app.prevFlag ~= safetyFlag
                app.env.setState(~safetyFlag)
                app.prevFlag = safetyFlag;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clf;  
            app.outputBar.Value = "Loading environment.";
            app.env = environment();
            app.outputBar.Value = "Loading niryoOne..";

            % Set up Robot 1
            app.myNiryoOne = niryoOne(transl(0.54, -0.01, 0));    
            app.myNiryoOne.model.animate([app.myNiryoOneLink1,app.myNiryoOneLink2,app.myNiryoOneLink3,app.myNiryoOneLink4,app.myNiryoOneLink5,app.myNiryoOneLink6]);  
            app.outputBar.Value = "Loading UR3...";
            % Set up Robot 2
            app.myUR3 = UR3(transl(1.02, -0.01, 0));                                            
            app.myUR3.model.animate([app.myUR3Link1,app.myUR3Link2,app.myUR3Link3,app.myUR3Link4,app.myUR3Link5,app.myUR3Link6]); 
            app.outputBar.Value = "Loading pigs, sponges and plates....";
            % Set up Piggy
            app.piggy = RobotPiggy(transl(0.8, -1.3, -0.7971));
            % Set up plate
            app.plate = RobotPlate(app.initialPlatePose);
            % Set up sponge
            app.sponge = RobotSponge(app.initialSpongePose);

            pause(1)
            app.outputBar.Value = "Yay, I finished loading :)";

            % app.esp32 = serialport("COM5",9600);
        end

        % Value changing function: JOINT1Knob
        function JOINT1KnobValueChanging(app, event)
            changingJJointValue = event.Value; % get knob value
            app.myUR3Link1 = deg2rad(changingJJointValue); % set the link angle to that value
            app.myUR3.model.animate([app.myUR3Link1,app.myUR3Link2,app.myUR3Link3,app.myUR3Link4,app.myUR3Link5,app.myUR3Link6]); % animate it
            % it works without draw now.
        end

        % Value changing function: JOINT2Knob
        function JOINT2KnobValueChanging(app, event)
            changingJJointValue = event.Value;
            app.myUR3Link2 = deg2rad(changingJJointValue);
            app.myUR3.model.animate([app.myUR3Link1,app.myUR3Link2,app.myUR3Link3,app.myUR3Link4,app.myUR3Link5,app.myUR3Link6]);
        end

        % Value changing function: JOINT3Knob
        function JOINT3KnobValueChanging(app, event)
            changingJJointValue = event.Value;
            app.myUR3Link3 = deg2rad(changingJJointValue);
            app.myUR3.model.animate([app.myUR3Link1,app.myUR3Link2,app.myUR3Link3,app.myUR3Link4,app.myUR3Link5,app.myUR3Link6]);
        end

        % Value changing function: JOINT4Knob
        function JOINT4KnobValueChanging(app, event)
            changingJJointValue = event.Value;
            app.myUR3Link4 = deg2rad(changingJJointValue);
            app.myUR3.model.animate([app.myUR3Link1,app.myUR3Link2,app.myUR3Link3,app.myUR3Link4,app.myUR3Link5,app.myUR3Link6]);
        end

        % Value changing function: JOINT5Knob
        function JOINT5KnobValueChanging(app, event)
            changingJJointValue = event.Value;
            app.myUR3Link5 = deg2rad(changingJJointValue);
            app.myUR3.model.animate([app.myUR3Link1,app.myUR3Link2,app.myUR3Link3,app.myUR3Link4,app.myUR3Link5,app.myUR3Link6]);
        end

        % Value changing function: JOINT6Knob
        function JOINT6KnobValueChanging(app, event)
            changingJJointValue = event.Value;
            app.myUR3Link6 = deg2rad(changingJJointValue);
            app.myUR3.model.animate([app.myUR3Link1,app.myUR3Link2,app.myUR3Link3,app.myUR3Link4,app.myUR3Link5,app.myUR3Link6]);
        end

        % Value changing function: JOINT1Knob_2
        function JOINT1Knob_2ValueChanging(app, event)
            changingJJointValue = event.Value;
            app.myNiryoOneLink1 = deg2rad(changingJJointValue);
            app.myNiryoOne.model.animate([app.myNiryoOneLink1,app.myNiryoOneLink2,app.myNiryoOneLink3,app.myNiryoOneLink4,app.myNiryoOneLink5,app.myNiryoOneLink6]);
        end

        % Value changing function: JOINT2Knob_2
        function JOINT2Knob_2ValueChanging(app, event)
            changingJJointValue = event.Value;
            app.myNiryoOneLink2 = deg2rad(changingJJointValue);
            app.myNiryoOne.model.animate([app.myNiryoOneLink1,app.myNiryoOneLink2,app.myNiryoOneLink3,app.myNiryoOneLink4,app.myNiryoOneLink5,app.myNiryoOneLink6]);
        end

        % Value changing function: JOINT3Knob_2
        function JOINT3Knob_2ValueChanging(app, event)
            changingJJointValue = event.Value;
            app.myNiryoOneLink3 = deg2rad(changingJJointValue);
            app.myNiryoOne.model.animate([app.myNiryoOneLink1,app.myNiryoOneLink2,app.myNiryoOneLink3,app.myNiryoOneLink4,app.myNiryoOneLink5,app.myNiryoOneLink6]);
        end

        % Value changing function: JOINT4Knob_2
        function JOINT4Knob_2ValueChanging(app, event)
            changingJJointValue = event.Value;
            app.myNiryoOneLink4 = deg2rad(changingJJointValue);
            app.myNiryoOne.model.animate([app.myNiryoOneLink1,app.myNiryoOneLink2,app.myNiryoOneLink3,app.myNiryoOneLink4,app.myNiryoOneLink5,app.myNiryoOneLink6]);
        end

        % Value changing function: JOINT5Knob_2
        function JOINT5Knob_2ValueChanging(app, event)
            changingJJointValue = event.Value;
            app.myNiryoOneLink5 = deg2rad(changingJJointValue);
            app.myNiryoOne.model.animate([app.myNiryoOneLink1,app.myNiryoOneLink2,app.myNiryoOneLink3,app.myNiryoOneLink4,app.myNiryoOneLink5,app.myNiryoOneLink6]);
        end

        % Value changing function: JOINT6Knob_2
        function JOINT6Knob_2ValueChanging(app, event)
            changingJJointValue = event.Value;
            app.myNiryoOneLink6 = deg2rad(changingJJointValue);
            app.myNiryoOne.model.animate([app.myNiryoOneLink1,app.myNiryoOneLink2,app.myNiryoOneLink3,app.myNiryoOneLink4,app.myNiryoOneLink5,app.myNiryoOneLink6]);
        end

        % Button pushed function: ESTOPButton
        function ESTOPButtonPushed(app, event)
            % same as hardware estop, but I couldn't call this due to it
            % being private
            app.eStopFlag = app.eStopFlag * -1;
            if app.eStopFlag > 0
                app.resumeFlag = 1;
                app.Lamp.Color = [1 0 0];
                debuggingOutput= "E-stop armed";
                app.Switch_2.Value = 'Pause';
                app.Lamp_2.Color = [1 0 0];
            else
                app.eStopFlag = -1;
                app.Lamp.Color = [1 1 0];
                debuggingOutput = "E-stop disarmed";
            end

            app.outputBar.Value = debuggingOutput;            
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            % if we flick the resume and the estop is off
            if app.Switch_2.Value == "Resume" && app.eStopFlag < 0
                app.resumeFlag = -1; % update flag
                app.Lamp_2.Color = [0 1 0];  % set gui light to green
                app.outputBar.Value = "Resuming...";
            end
        end

        % Value changing function: PigMoverSlider
        function PigMoverSliderValueChanging(app, event)
            changingValue = event.Value; % get slider value
            app.piggy.movePiggy(transl(0.8, changingValue, -0.7971)) % update pig translation by that much
        end

        % Button pushed function: RunSequenceButton
        function RunSequenceButtonPushed(app, event)
            app.clean(); 
        end

        % Button pushed function: MoveNiryoButton
        function MoveNiryoButtonPushed(app, event)
            % move robot to the end effector positions
            % I could make it more modular so that it works with UR3 as
            % well, but it would be a trek and won't save me time.
            xPos = app.XPosEditField.Value;
            yPos = app.YPosEditField.Value;
            zPos = app.ZPosEditField.Value;
            newJointPosition = app.myNiryoOne.model.ikcon(transl(xPos, yPos, zPos), [app.myNiryoOneLink1,app.myNiryoOneLink2,app.myNiryoOneLink3,app.myNiryoOneLink4,app.myNiryoOneLink5,app.myNiryoOneLink6]);
            app.myNiryoOne.model.animate(newJointPosition);
            drawnow
        end

        % Button pushed function: MoveUR3Button
        function MoveUR3ButtonPushed(app, event)
            % get value of XYZ input fields
            xPos = app.XPosEditField_2.Value;
            yPos = app.YPosEditField_2.Value;
            zPos = app.ZPosEditField_2.Value;

            % figure out what joints need to be
            newJointPosition = app.myUR3.model.ikcon(transl(xPos, yPos, zPos), [app.myUR3Link1,app.myUR3Link2,app.myUR3Link3,app.myUR3Link4,app.myUR3Link5,app.myUR3Link6]);

            % Move the robot there
            app.myUR3.model.animate(newJointPosition);
            drawnow
        end

        % Button pushed function: SensorPigTripButton
        function SensorPigTripButtonPushed(app, event)
            % this is an optional button to immediately move the pig to a 
            % guraranteed intersection position
            app.piggy.movePiggy(transl(0.8, -0.9, -0.7971));
            app.PigMoverSlider.Value = -0.9;
            app.outputBar.Value = "Look! The pig moved! :O";
        end

        % Button pushed function: GetPositionButton
        function GetPositionButtonPushed(app, event)
            % get transform of the current robot
            Tr = app.myUR3.model.fkine([app.myUR3Link1,app.myUR3Link2,app.myUR3Link3,app.myUR3Link4,app.myUR3Link5,app.myUR3Link6]).T;

            % update the field values
            app.XPosEditField_2.Value = Tr(1, 4);
            app.YPosEditField_2.Value = Tr(2, 4);
            app.ZPosEditField_2.Value = Tr(3, 4);
        end

        % Button pushed function: GetPositionButton_2
        function GetPositionButton_2Pushed(app, event)
            % get transform of the current robot
            Tr = app.myNiryoOne.model.fkine([app.myNiryoOneLink1,app.myNiryoOneLink2,app.myNiryoOneLink3,app.myNiryoOneLink4,app.myNiryoOneLink5,app.myNiryoOneLink6]).T;
            % update the field values
            app.XPosEditField.Value = Tr(1, 4);
            app.YPosEditField.Value = Tr(2, 4);
            app.ZPosEditField.Value = Tr(3, 4);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {379, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.NiryoTab = uitab(app.TabGroup);
            app.NiryoTab.Title = 'Niryo';
            app.JOINT1Knob_2Label = uilabel(app.NiryoTab);
            app.JOINT1Knob_2Label.Text = 'JOINT 1';
            app.JOINT1Knob_2 = uiknob(app.NiryoTab, 'continuous');
            app.JOINT1Knob_2.Limits = [-175 175];
            app.JOINT1Knob_2.ValueChangingFcn = createCallbackFcn(app, @JOINT1Knob_2ValueChanging, true);
            app.JOINT2Knob_2Label = uilabel(app.NiryoTab);
            app.JOINT2Knob_2Label.Text = 'JOINT 2';
            app.JOINT2Knob_2 = uiknob(app.NiryoTab, 'continuous');
            app.JOINT2Knob_2.Limits = [-36.7 90];
            app.JOINT2Knob_2.ValueChangingFcn = createCallbackFcn(app, @JOINT2Knob_2ValueChanging, true);
            app.JOINT3Knob_2Label = uilabel(app.NiryoTab);
            app.JOINT3Knob_2Label.Text = 'JOINT 3';
            app.JOINT3Knob_2 = uiknob(app.NiryoTab, 'continuous');
            app.JOINT3Knob_2.Limits = [-90 80];
            app.JOINT3Knob_2.ValueChangingFcn = createCallbackFcn(app, @JOINT3Knob_2ValueChanging, true);
            app.JOINT4Knob_2Label = uilabel(app.NiryoTab);
            app.JOINT4Knob_2Label.Text = 'JOINT 4';
            app.JOINT4Knob_2 = uiknob(app.NiryoTab, 'continuous');
            app.JOINT4Knob_2.Limits = [-175 175];
            app.JOINT4Knob_2.ValueChangingFcn = createCallbackFcn(app, @JOINT4Knob_2ValueChanging, true);
            app.JOINT5Knob_2Label = uilabel(app.NiryoTab);
            app.JOINT5Knob_2Label.Text = 'JOINT 5';
            app.JOINT5Knob_2 = uiknob(app.NiryoTab, 'continuous');
            app.JOINT5Knob_2.Limits = [-100 110];
            app.JOINT5Knob_2.ValueChangingFcn = createCallbackFcn(app, @JOINT5Knob_2ValueChanging, true);
            app.JOINT6Knob_2Label = uilabel(app.NiryoTab);
            app.JOINT6Knob_2Label.Text = 'JOINT 6';
            app.JOINT6Knob_2 = uiknob(app.NiryoTab, 'continuous');
            app.JOINT6Knob_2.Limits = [-147.5 147.5];
            app.JOINT6Knob_2.ValueChangingFcn = createCallbackFcn(app, @JOINT6Knob_2ValueChanging, true);
            app.XPosEditFieldLabel = uilabel(app.NiryoTab);
            app.XPosEditFieldLabel.Text = 'X Pos';
            app.XPosEditField = uieditfield(app.NiryoTab, 'numeric');
            app.YPosEditFieldLabel = uilabel(app.NiryoTab);
            app.YPosEditFieldLabel.Text = 'Y Pos';
            app.YPosEditField = uieditfield(app.NiryoTab, 'numeric');
            app.ZPosEditFieldLabel = uilabel(app.NiryoTab);
            app.ZPosEditFieldLabel.Text = 'Z Pos';
            app.ZPosEditField = uieditfield(app.NiryoTab, 'numeric');
            app.EndEffectorMoverLabel = uilabel(app.NiryoTab);
            app.EndEffectorMoverLabel.Text = 'End Effector Mover';
            app.MoveNiryoButton = uibutton(app.NiryoTab, 'push');
            app.MoveNiryoButton.ButtonPushedFcn = createCallbackFcn(app, @MoveNiryoButtonPushed, true);
            app.MoveNiryoButton.Text = 'Move Niryo';
            app.GetPositionButton_2 = uibutton(app.NiryoTab, 'push');
            app.GetPositionButton_2.ButtonPushedFcn = createCallbackFcn(app, @GetPositionButton_2Pushed, true);
            app.GetPositionButton_2.Text = 'Get Position';
            app.UR3Tab = uitab(app.TabGroup);
            app.UR3Tab.Title = 'UR3';
            app.JOINT1KnobLabel = uilabel(app.UR3Tab);
            app.JOINT1KnobLabel.Text = 'JOINT 1';
            app.JOINT1Knob = uiknob(app.UR3Tab, 'continuous');
            app.JOINT1Knob.Limits = [-360 360];
            app.JOINT1Knob.ValueChangingFcn = createCallbackFcn(app, @JOINT1KnobValueChanging, true);
            app.JOINT2KnobLabel = uilabel(app.UR3Tab);
            app.JOINT2KnobLabel.Text = 'JOINT 2';
            app.JOINT2Knob = uiknob(app.UR3Tab, 'continuous');
            app.JOINT2Knob.Limits = [-360 360];
            app.JOINT2Knob.ValueChangingFcn = createCallbackFcn(app, @JOINT2KnobValueChanging, true);
            app.JOINT2Knob.Value = -90;
            app.JOINT3KnobLabel = uilabel(app.UR3Tab);
            app.JOINT3KnobLabel.Text = 'JOINT 3';
            app.JOINT3Knob = uiknob(app.UR3Tab, 'continuous');
            app.JOINT3Knob.Limits = [-360 360];
            app.JOINT3Knob.ValueChangingFcn = createCallbackFcn(app, @JOINT3KnobValueChanging, true);
            app.JOINT4KnobLabel = uilabel(app.UR3Tab);
            app.JOINT4KnobLabel.Text = 'JOINT 4';
            app.JOINT4Knob = uiknob(app.UR3Tab, 'continuous');
            app.JOINT4Knob.Limits = [-360 360];
            app.JOINT4Knob.ValueChangingFcn = createCallbackFcn(app, @JOINT4KnobValueChanging, true);
            app.JOINT5KnobLabel = uilabel(app.UR3Tab);
            app.JOINT5KnobLabel.Text = 'JOINT 5';
            app.JOINT5Knob = uiknob(app.UR3Tab, 'continuous');
            app.JOINT5Knob.Limits = [-360 360];
            app.JOINT5Knob.ValueChangingFcn = createCallbackFcn(app, @JOINT5KnobValueChanging, true);
            app.JOINT5Knob.Value = -90;
            app.JOINT6KnobLabel = uilabel(app.UR3Tab);
            app.JOINT6KnobLabel.Text = 'JOINT 6';
            app.JOINT6Knob = uiknob(app.UR3Tab, 'continuous');
            app.JOINT6Knob.Limits = [-360 360];
            app.JOINT6Knob.ValueChangingFcn = createCallbackFcn(app, @JOINT6KnobValueChanging, true);
            app.EndEffectorMoverLabel_2 = uilabel(app.UR3Tab);
            app.EndEffectorMoverLabel_2.Text = 'End Effector Mover';
            app.MoveUR3Button = uibutton(app.UR3Tab, 'push');
            app.MoveUR3Button.ButtonPushedFcn = createCallbackFcn(app, @MoveUR3ButtonPushed, true);
            app.MoveUR3Button.Text = 'Move UR3';
            app.XPosEditField_2Label = uilabel(app.UR3Tab);
            app.XPosEditField_2Label.Text = 'X Pos';
            app.XPosEditField_2 = uieditfield(app.UR3Tab, 'numeric');
            app.YPosEditField_2Label = uilabel(app.UR3Tab);
            app.YPosEditField_2Label.Text = 'Y Pos';
            app.YPosEditField_2 = uieditfield(app.UR3Tab, 'numeric');
            app.ZPosEditField_2Label = uilabel(app.UR3Tab);
            app.ZPosEditField_2Label.Text = 'Z Pos';
            app.ZPosEditField_2 = uieditfield(app.UR3Tab, 'numeric');
            app.GetPositionButton = uibutton(app.UR3Tab, 'push');
            app.GetPositionButton.ButtonPushedFcn = createCallbackFcn(app, @GetPositionButtonPushed, true);
            app.GetPositionButton.Text = 'Get Position';
            app.DebuggingetcPanel = uipanel(app.LeftPanel);
            app.DebuggingetcPanel.Title = 'Debugging, etc';
            app.outputBar = uieditfield(app.DebuggingetcPanel, 'text');
            app.RightPanel = uipanel(app.GridLayout);
            app.Lamp = uilamp(app.RightPanel);
            app.ESTOPButton = uibutton(app.RightPanel, 'push');
            app.ESTOPButton.ButtonPushedFcn = createCallbackFcn(app, @ESTOPButtonPushed, true);
            app.ESTOPButton.Text = 'ESTOP';
            app.Switch_2 = uiswitch(app.RightPanel, 'toggle');
            app.Switch_2.Items = {'Pause', 'Resume'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 'Resume';
            app.Lamp_2 = uilamp(app.RightPanel);
            app.RunSequenceButton = uibutton(app.RightPanel, 'push');
            app.RunSequenceButton.ButtonPushedFcn = createCallbackFcn(app, @RunSequenceButtonPushed, true);
            app.RunSequenceButton.Text = 'Run Sequence';
            app.PigMoverSliderLabel = uilabel(app.RightPanel);
            app.PigMoverSliderLabel.Text = 'Pig Mover';
            app.PigMoverSlider = uislider(app.RightPanel);
            app.PigMoverSlider.Limits = [-1.3 -0.3];
            app.PigMoverSlider.ValueChangingFcn = createCallbackFcn(app, @PigMoverSliderValueChanging, true);
            app.PigMoverSlider.Value = -1.3;
            app.SensorPigTripButton = uibutton(app.RightPanel, 'push');
            app.SensorPigTripButton.ButtonPushedFcn = createCallbackFcn(app, @SensorPigTripButtonPushed, true);
            app.SensorPigTripButton.Text = 'Sensor Pig Trip';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end